classdef InvIPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        BienvenidaTab                   matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout34                    matlab.ui.container.GridLayout
        GridLayout35                    matlab.ui.container.GridLayout
        Switch                          matlab.ui.control.Switch
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        HTML_Inicio                     matlab.ui.control.HTML
        ExploraciondealgoritmosTab      matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout32                    matlab.ui.container.GridLayout
        CargarimagengroundtruthopcionalButton  matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        CargarimagendepiezametlicaButton  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UIAxes_original                 matlab.ui.control.UIAxes
        UIAxes_ground_truth             matlab.ui.control.UIAxes
        GridLayout29                    matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        NmerodecentrosLabel             matlab.ui.control.Label
        NmerodecentrosEditField         matlab.ui.control.NumericEditField
        MtododeagrupamientoButtonGroup  matlab.ui.container.ButtonGroup
        InformacinespacialButton        matlab.ui.control.RadioButton
        GaussianMixturesButton          matlab.ui.control.RadioButton
        FuzzyCMeansButton               matlab.ui.control.RadioButton
        KMeansButton                    matlab.ui.control.RadioButton
        MtodoinvarianteButtonGroup      matlab.ui.container.ButtonGroup
        PCAButton                       matlab.ui.control.RadioButton
        UpcroftButton                   matlab.ui.control.RadioButton
        KrajnkButton                    matlab.ui.control.RadioButton
        MaddernButton                   matlab.ui.control.RadioButton
        AlvarezButton                   matlab.ui.control.RadioButton
        EjecutarButton                  matlab.ui.control.Button
        GuardarresultadosButton         matlab.ui.control.Button
        ResultadosPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes_invariante_segmentada    matlab.ui.control.UIAxes
        UIAxes_invariante_resultado     matlab.ui.control.UIAxes
        UIAxes_original_segmentada      matlab.ui.control.UIAxes
        UIAxes_original_resultado       matlab.ui.control.UIAxes
        CacheTab                        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout33                    matlab.ui.container.GridLayout
        BorrarCacheButton               matlab.ui.control.Button
        VerimgenesButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AyudaTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Menu_Ayuda                      matlab.ui.container.TabGroup
        FuncionamientoTab               matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        HTML_funcionamiento             matlab.ui.control.HTML
        MetodosInvariantesTab           matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        TabInvariantesTipos             matlab.ui.container.TabGroup
        AlvarezTab                      matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        HTML_Alvarez                    matlab.ui.control.HTML
        MaddernTab                      matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        HTML_Maddern                    matlab.ui.control.HTML
        KrajnkTab                       matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        HTML_Krajnik                    matlab.ui.control.HTML
        UpcrofTab                       matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        HTML_Upcrof                     matlab.ui.control.HTML
        PCATab                          matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        HTML_PCA                        matlab.ui.control.HTML
        MetodosAgrupamientoTab          matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        TabAgrupamientoTipos            matlab.ui.container.TabGroup
        KMeansTab                       matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        HTML_K_Means                    matlab.ui.control.HTML
        CMeansTab                       matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        HTML_Fuzzy_C_Means              matlab.ui.control.HTML
        GMMTab                          matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        HTML_GMM                        matlab.ui.control.HTML
        SpatialTab                      matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        HTML_Spatial                    matlab.ui.control.HTML
        PorcentajeAciertoTab            matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        HTML_Acierto                    matlab.ui.control.HTML
        MemoriaCacheTab                 matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        HTML_Cache                      matlab.ui.control.HTML
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    % Funciones auxiliares
    properties (Access = public)
        imagen
        imagen_ground_truth
        imagen_en_tres_canales
        nombre_imagen
        extension
        invariante
        agrupamiento
        numClusters
        imagen_original
        imagen_original_agrup
        imagen_invariante
        imagen_invariante_agrup
        porcentaje_original
        porcentaje_invariante
        tiene_img_ground_truth
        cachePath
        livescriptsPath
        Canceling
        idioma
        imagen_text
        archivo_img
        no_extension
        sel_img
        cargar_otra_img
        cargando_img
        espere
        img_orig
        leyendo_img
        buscando_gt
        img_gt
        prep_ejec
        ejec_alg
        comp_cache
        aplicando
        sobre_img
        sobre_img_orig
        sobre_img_inv
        guard_cache
        orig_agrup
        inv_agrup
        guardar_res
        proc_compl
        sel_ubicacion
        guardando_imgs
        guardado_imgs
        guard_img_orig
        guard_img_orig_agrup
        con_centros
        centros
        acierto
        guard_img_inv
        agrup_str
        imgs_guardadas
        int_guardar_nuev
        imgs_gt
        selecciona_img_gt
        cargar_otra_gt
        seguro_salir
        seguro_borrar
        conf_cierre
        conf_borrado
        salir
        borrar
        cancelar
        seleccion_cancelada
        cargar_img_para_ejec
        sel_img_cancelada
        img_gt_no_encontrada
        cargar_img_gt_para_comp
        no_ubicacion_sel
        ubicacion_no_sel
        imgs_guardadas_correct
        guardado_exitoso
        error_guardar_imgs
        error_guardado
        sel_img_gt_cancelada
        error_lectura_gt
        error_lectura
        centros_introducidos_menor
        menor_minimo_2
        centros_introducidos_alta
        valor_alto_recomendado
        error_num_centros
        cache_borrada
        alerta
        error_ejec
        error_titulo
    end

    methods (Access = private)
        function addImageToUITable(app, nombre, fecha, invariante, agrupamiento, numClusters, porcentaje)
            fecha = datetime(fecha, 'InputFormat', 'yyyyMMddHHmmss');
        
            % Si hay campos vacios
            if isempty(invariante)
                invariante = 'No aplicado';
            end
        
            if isempty(agrupamiento)
                agrupamiento = 'No aplicado';
            end
        
            if isempty(numClusters)
                numClusters = '-';
            end
        
            if isempty(porcentaje)
                medidaComparacion = 'No';
                valor_porcentaje = num2str(porcentaje);
            else
                medidaComparacion = 'Sí';
                valor_porcentaje = [num2str(porcentaje) '%'];
            end

            % Crear una nueva fila para la tabla
            newRow = {nombre, fecha, invariante, agrupamiento, numClusters, medidaComparacion, valor_porcentaje, false};
        
            % Convertir la nueva fila a una tabla
            newRowTable = cell2table(newRow, 'VariableNames', app.UITable.Data.Properties.VariableNames);
        
            % Actualizar la tabla
            app.UITable.Data = [app.UITable.Data; newRowTable];
        end

        function loadCacheTable(app)
            % Lista de archivos en el directorio de caché
            cacheFiles = dir(fullfile(app.cachePath, '*.*'));
            cacheFiles = cacheFiles(~ismember({cacheFiles.name}, {'.', '..'}));
            
            % Expresiones regulares para los tres tipos de archivos
            pattern1 = '(\d{14})_\d+__([^_]+(?:_[^_]+)*)__([^_]+)\.[a-zA-Z0-9]+';
            pattern2 = '(\d{14})_\d+__([^_]+(?:_[^_]+)*)__Original_([^_]+)_(\d+)_c_?(\d+\.\d+)?%?\.[a-zA-Z0-9]+';
            pattern3 = '(\d{14})_\d+__([^_]+(?:_[^_]+)*)__([^_]+)_([^_]+)_(\d+)_c_?(\d+\.\d+)?%?\.[a-zA-Z0-9]+';
            
            % Iterar sobre los archivos y extraer información
            for cacheFile = cacheFiles'
                fileName = cacheFile.name;
        
                % Para el patrón tipo 1
                match = regexp(fileName, pattern1, 'tokens');
                if ~isempty(match)
                    tokens = match{1};
                    fecha = tokens{1};
                    nombre = tokens{2};
                    inv = tokens{3};
                    addImageToUITable(app, nombre, fecha, inv, '', '', '');
                    continue;
                end
                
                % Para el patrón tipo 2
                match = regexp(fileName, pattern2, 'tokens');
                if ~isempty(match)
                    tokens = match{1};
                    fecha = tokens{1};
                    nombre = tokens{2};
                    agrup = tokens{3};
                    clusters = tokens{4};
                    porcentaje = '';
                    if length(tokens) > 4 && ~isempty(tokens{5})
                        porcentaje = tokens{5};
                    end
                    addImageToUITable(app, nombre, fecha, '', agrup, clusters, porcentaje);
                    continue;
                end
                
                % Para el patrón tipo 3
                match = regexp(fileName, pattern3, 'tokens');
                if ~isempty(match)
                    tokens = match{1};
                    fecha = tokens{1};
                    nombre = tokens{2};
                    inv = tokens{3};
                    agrup = tokens{4};
                    clusters = tokens{5};
                    porcentaje = '';
                    if length(tokens) > 5 && ~isempty(tokens{6})
                        porcentaje = tokens{6};
                    end
                    addImageToUITable(app, nombre, fecha, inv, agrup, clusters, porcentaje);
                    continue;
                end
            end
        end

        function bloqueoDesbloqueoOpciones(app, modo)
            if modo == 1
                % Bloqueo opciones
                app.MtodoinvarianteButtonGroup.Enable = "off";
                app.MtododeagrupamientoButtonGroup.Enable = "off";
                app.NmerodecentrosEditField.Enable = "off";
            else
                % Desbloqueo opciones
                app.MtodoinvarianteButtonGroup.Enable = "on";
                app.MtododeagrupamientoButtonGroup.Enable = "on";
                app.NmerodecentrosEditField.Enable = "on";
            end
        end

        function cargarTitulo(app)
            if strcmp(app.idioma, 'ES')
                app.UIFigure.Name = 'Invariantes de Iluminación en piezas metálicas';
            else
                app.UIFigure.Name = 'Lighting Invariants in Metal Pieces';
            end
        end

        function cargarInterfaz(app)
            if strcmp(app.idioma, 'ES')
                app.BienvenidaTab.Title = 'Bienvenida';
                app.ExploraciondealgoritmosTab.Title = 'Exploración de algoritmos';
                app.CargarimagendepiezametlicaButton.Text = 'Cargar imagen de pieza metálica';
                app.CargarimagengroundtruthopcionalButton.Text = 'Cargar imagen ground truth (opcional)';
                app.EjecutarButton.Text = 'Ejecutar';
                app.GuardarresultadosButton.Text = 'Guardar resultados';
                app.UIAxes_original.Title.String = 'Imagen original';
                app.UIAxes_ground_truth.Title.String = 'Imagen ground truth';
                app.UIAxes_original_resultado.Title.String = 'Imagen original';
                app.UIAxes_original_segmentada.Title.String = 'Original segmentada';
                app.UIAxes_invariante_resultado.Title.String = 'Imagen invariante';
                app.UIAxes_invariante_segmentada.Title.String = 'Invariante segmentada';
                app.MtodoinvarianteButtonGroup.Title = 'Método invariante';
                app.NmerodecentrosLabel.Text = 'Número de centros';
                app.MtododeagrupamientoButtonGroup.Title = 'Método de agrupamiento';
                app.ResultadosPanel.Title = 'Resultados';
                app.CacheTab.Title = 'Histórico de exploraciones';
                app.UITable.ColumnName = {'Nombre del fichero', 'Fecha', 'Algoritmo invariante', 'Algoritmo de agrupamiento', 'Número de centros', 'Imagen ground truth', 'Medida de calidad', 'Archivo'};
                app.VerimgenesButton.Text = 'Ver imágenes';
                app.BorrarCacheButton.Text = 'Borrar Datos';
                app.AyudaTab.Title = 'Ayuda';
                app.FuncionamientoTab.Title = 'Funcionamiento';
                app.MetodosInvariantesTab.Title = 'Métodos invariantes';
                app.MetodosAgrupamientoTab.Title = 'Métodos de agrupamiento';
                app.PorcentajeAciertoTab.Title = 'Porcentaje de acierto';
                app.MemoriaCacheTab.Title = 'Memoria caché';
            else
                app.BienvenidaTab.Title = 'Home';
                app.ExploraciondealgoritmosTab.Title = 'Algorithm Exploration';
                app.CargarimagendepiezametlicaButton.Text = 'Load Metal Piece Image';
                app.CargarimagengroundtruthopcionalButton.Text = 'Load Ground Truth Image (optional)';
                app.EjecutarButton.Text = 'Execute';
                app.GuardarresultadosButton.Text = 'Save Results';
                app.UIAxes_original.Title.String = 'Original Image';
                app.UIAxes_ground_truth.Title.String = 'Ground Truth Image';
                app.UIAxes_original_resultado.Title.String = 'Original Image';
                app.UIAxes_original_segmentada.Title.String = 'Segmented Original';
                app.UIAxes_invariante_resultado.Title.String = 'Invariant Image';
                app.UIAxes_invariante_segmentada.Title.String = 'Segmented Invariant';
                app.MtodoinvarianteButtonGroup.Title = 'Invariant Method';
                app.NmerodecentrosLabel.Text = 'Number of Centers';
                app.MtododeagrupamientoButtonGroup.Title = 'Clustering Method';
                app.ResultadosPanel.Title = 'Results';
                app.CacheTab.Title = 'Exploration History';
                app.UITable.ColumnName = {'File Name', 'Date', 'Invariant Algorithm', 'Clustering Algorithm', 'Number of Centers', 'Ground Truth Image', 'Quality Measure', 'File'};
                app.VerimgenesButton.Text = 'View images';
                app.BorrarCacheButton.Text = 'Clear Data';
                app.AyudaTab.Title = 'Help';
                app.FuncionamientoTab.Title = 'Functionality';
                app.MetodosInvariantesTab.Title = 'Invariant Methods';
                app.MetodosAgrupamientoTab.Title = 'Clustering Methods';
                app.PorcentajeAciertoTab.Title = 'Accuracy Percentage';
                app.MemoriaCacheTab.Title = 'Cache Memory';
            end
        end

        function cargarStrings(app)
            if strcmp(app.idioma, 'ES')
                app.sel_img = 'Selecciona una imagen';
                app.cargar_otra_img = 'Cargar otra imagen';
                app.cargando_img = 'Cargando imagen...';
                app.espere = 'Por favor, espere';
                app.img_orig = 'Imagen original';
                app.leyendo_img = 'Leyendo imagen...';
                app.buscando_gt = 'Buscando imagen ground truth...';
                app.img_gt = 'Imagen ground truth';
                app.prep_ejec = 'Preparando ejecución...';
                app.ejec_alg = 'Ejecución de algoritmos';
                app.comp_cache = 'Comprobando caché...';
                app.aplicando = 'Aplicando ';
                app.sobre_img = ' sobre la imagen...';
                app.sobre_img_orig = ' sobre la imagen original...';
                app.sobre_img_inv = ' sobre la imagen invariante...';
                app.guard_cache = 'Guardando imagenes en caché...';
                app.orig_agrup = 'Original agrupada';
                app.inv_agrup = 'Invariante agrupada';
                app.guardar_res = 'Guardar resultados';
                app.proc_compl = 'Proceso completado.';
                app.sel_ubicacion = 'Seleccione una ubicación para guardar las imágenes';
                app.guardando_imgs = 'Guardando imágenes...';
                app.guardado_imgs = 'Guardado de imágenes';
                app.guard_img_orig = 'Guardando imagen original...';
                app.guard_img_orig_agrup = 'Guardando imagen original agrupada (';
                app.con_centros = ' con ';
                app.centros = ' centros) ...';
                app.acierto = 'Acierto: ';
                app.guard_img_inv = 'Guardando imagen invariante (';
                app.agrup_str = ' agrupada (';
                app.imgs_guardadas = 'Imágenes Guardadas';
                app.int_guardar_nuev = 'Intentar Guardar Nuevamente';
                app.imgs_gt = 'Imagenes de ground truth (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)';
                app.selecciona_img_gt = 'Selecciona una imagen ground truth';
                app.cargar_otra_gt = 'Cargar otra imagen ground truth';
                app.seguro_salir = '¿Estás seguro que deseas salir?';
                app.seguro_borrar = '¿Estás seguro de borrar la caché?';
                app.conf_cierre = 'Confirmar Cierre';
                app.conf_borrado = 'Confirmar Borrado';
                app.salir = 'Salir';
                app.borrar = 'Borrar';
                app.cancelar = 'Cancelar';
                app.imagen_text = 'Imagen';
                app.archivo_img = 'El archivo de imagen: ';
                app.no_extension = ' no existe en ninguna extensión común.';
            else
                app.sel_img = 'Select an image';
                app.cargar_otra_img = 'Load another image';
                app.cargando_img = 'Loading image...';
                app.espere = 'Please wait';
                app.img_orig = 'Original image';
                app.leyendo_img = 'Reading image...';
                app.buscando_gt = 'Searching for ground truth image...';
                app.img_gt = 'Ground truth image';
                app.prep_ejec = 'Preparing execution...';
                app.ejec_alg = 'Algorithm execution';
                app.comp_cache = 'Checking cache...';
                app.aplicando = 'Applying ';
                app.sobre_img = ' on the image...';
                app.sobre_img_orig = ' on the original image...';
                app.sobre_img_inv = ' on the invariant image...';
                app.guard_cache = 'Saving images to cache...';
                app.orig_agrup = 'Grouped original';
                app.inv_agrup = 'Grouped invariant';
                app.guardar_res = 'Save results';
                app.proc_compl = 'Process completed.';
                app.sel_ubicacion = 'Select a location to save the images';
                app.guardando_imgs = 'Saving images...';
                app.guardado_imgs = 'Image saving';
                app.guard_img_orig = 'Saving original image...';
                app.guard_img_orig_agrup = 'Saving grouped original image (';
                app.con_centros = ' with ';
                app.centros = ' centers) ...';
                app.acierto = 'Success: ';
                app.guard_img_inv = 'Saving invariant image (';
                app.agrup_str = ' grouped (';
                app.imgs_guardadas = 'Images saved';
                app.int_guardar_nuev = 'Try saving again';
                app.imgs_gt = 'Ground truth images (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)';
                app.selecciona_img_gt = 'Select a ground truth image';
                app.cargar_otra_gt = 'Load another ground truth image';
                app.seguro_salir = 'Are you sure you want to exit?';
                app.seguro_borrar = 'Are you sure about clearing the cache?';
                app.conf_cierre = 'Confirm Exit';
                app.conf_borrado = 'Confirm Deletion';
                app.salir = 'Exit';
                app.borrar = 'Clear';
                app.cancelar = 'Cancel';
                app.imagen_text = 'Image';
                app.archivo_img = 'The image file: ';
                app.no_extension = ' does not exist in any common extension.';
            end
        end

        function cargarAlerts(app)
            if strcmp(app.idioma, 'ES')
                app.seleccion_cancelada = 'Se ha cancelado la selección de imágenes.';
                app.cargar_img_para_ejec = 'Carga una imagen para poder ejecutar.';
                app.sel_img_cancelada = 'Selección de imagen cancelada';
                app.img_gt_no_encontrada = 'Imagen ground truth no encontrada';
                app.cargar_img_gt_para_comp = 'Carga una imagen ground truth para poder comparar.';
                app.no_ubicacion_sel = 'No seleccionó una ubicación, se usarán los valores por defecto.';
                app.ubicacion_no_sel = 'Ubicación no seleccionada';
                app.imgs_guardadas_correct = 'Todas las imágenes se han guardado correctamente.';
                app.guardado_exitoso = 'Guardado Exitoso';
                app.error_guardar_imgs = 'Error al guardar imágenes: ';
                app.error_guardado = 'Error de Guardado';
                app.sel_img_gt_cancelada = 'Se ha cancelado la selección de imagen ground truth.';
                app.sel_img_gt_cancelada = 'Selección de imagen ground truth cancelada';
                app.error_lectura_gt = 'Error al leer la imagen ground truth: ';
                app.error_lectura = 'Error de lectura';
                app.centros_introducidos_menor = 'La cantidad de centros introducida (';
                app.menor_minimo_2 = ') es menor al número mínimo, 2';
                app.centros_introducidos_alta = ') es muy elevada, el valor recomendado es entre 2 y 5';
                app.valor_alto_recomendado = ') es elevada, el valor recomendado es entre 2 y 5';
                app.error_num_centros = 'Error en Número de Centros';
                app.cache_borrada = 'Cache borrada exitosamente.';
                app.alerta = 'Alerta';
                app.error_ejec = 'Ha ocurrido un error durante la ejecución. Por favor, intente de nuevo.';
                app.error_titulo = 'Error en ejecución';
            else
                app.seleccion_cancelada = 'Image selection has been canceled.';
                app.cargar_img_para_ejec = 'Load an image to execute.';
                app.sel_img_cancelada = 'Image selection canceled';
                app.img_gt_no_encontrada = 'Ground truth image not found';
                app.cargar_img_gt_para_comp = 'Load a ground truth image to compare.';
                app.no_ubicacion_sel = 'No location selected, default values will be used.';
                app.ubicacion_no_sel = 'Location not selected';
                app.imgs_guardadas_correct = 'All images have been saved successfully.';
                app.guardado_exitoso = 'Successful save';
                app.error_guardar_imgs = 'Error saving images: ';
                app.error_guardado = 'Save error';
                app.sel_img_gt_cancelada = 'Ground truth image selection has been canceled.';
                app.sel_img_gt_cancelada = 'Ground truth image selection canceled';
                app.error_lectura_gt = 'Error reading ground truth image: ';
                app.error_lectura = 'Read error';
                app.centros_introducidos_menor = 'The number of centers entered (';
                app.menor_minimo_2 = ') is less than the minimum number, 2';
                app.centros_introducidos_alta = ') is too high, the recommended value is between 2 and 5';
                app.valor_alto_recomendado = ') is high, the recommended value is between 2 and 5';
                app.error_num_centros = 'Error in Number of Centers';
                app.cache_borrada = 'Cache cleared successfully.';
                app.alerta = 'Alert';
                app.error_ejec = 'An error occurred during execution. Please try again.';
                app.error_titulo = 'Error during execution';
            end
        end

        function cargarHTML(app)
            if strcmp(app.idioma, 'ES')
                app.HTML_Inicio.HTMLSource = fullfile(app.livescriptsPath, 'bienvenida_ES.html');
                app.HTML_funcionamiento.HTMLSource = fullfile(app.livescriptsPath, 'funcionamiento_ES.html');
                app.HTML_Alvarez.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_alvarez_ES.html');
                app.HTML_Maddern.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_maddern_ES.html');
                app.HTML_Krajnik.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_krajnik_ES.html');
                app.HTML_Upcrof.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_upcroft_ES.html');
                app.HTML_PCA.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_pca_ES.html');
                app.HTML_K_Means.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_kmeans_ES.html');
                app.HTML_Fuzzy_C_Means.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_cmeans_ES.html');
                app.HTML_GMM.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_gmm_ES.html');
                app.HTML_Spatial.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_spatial_ES.html');
                app.HTML_Acierto.HTMLSource = fullfile(app.livescriptsPath, 'porcentaje_acierto_ES.html');
                app.HTML_Cache.HTMLSource = fullfile(app.livescriptsPath, 'memoria_cache_ES.html');
            else
                app.HTML_Inicio.HTMLSource = fullfile(app.livescriptsPath, 'bienvenida_EN.html');
                app.HTML_funcionamiento.HTMLSource = fullfile(app.livescriptsPath, 'funcionamiento_EN.html');
                app.HTML_Alvarez.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_alvarez_EN.html');
                app.HTML_Maddern.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_maddern_EN.html');
                app.HTML_Krajnik.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_krajnik_EN.html');
                app.HTML_Upcrof.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_upcroft_EN.html');
                app.HTML_PCA.HTMLSource = fullfile(app.livescriptsPath, 'met_inv_pca_EN.html');
                app.HTML_K_Means.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_kmeans_EN.html');
                app.HTML_Fuzzy_C_Means.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_cmeans_EN.html');
                app.HTML_GMM.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_gmm_EN.html');
                app.HTML_Spatial.HTMLSource = fullfile(app.livescriptsPath, 'met_seg_spatial_EN.html');
                app.HTML_Acierto.HTMLSource = fullfile(app.livescriptsPath, 'porcentaje_acierto_EN.html');
                app.HTML_Cache.HTMLSource = fullfile(app.livescriptsPath, 'memoria_cache_EN.html');
            end
        end

        function cargarTooltips(app)
            if strcmp(app.idioma, 'ES')
                app.CargarimagendepiezametlicaButton.Tooltip = sprintf('Selecciona una imagen para aplicar los algoritmos de transformación invariante. Esta puede ser tanto del sistema como una propia.');
                app.CargarimagengroundtruthopcionalButton.Tooltip = sprintf('Selecciona una imagen ground truth para comparar con la imagen seleccionada a la cual se la aplicarán algoritmos de transformación invariante.');
                app.EjecutarButton.Tooltip = sprintf('Ejetutar algoritmos de trasformación invariante y agrupamiento seleccionados sobre la imagen selecionada.');
                app.GuardarresultadosButton.Tooltip = sprintf('Guardar las cuatro imagenes solución en el directorio seleccionado. Estas tendran un nombre acorde a los resultados que han obtenido.');
                app.NmerodecentrosEditField.Tooltip = sprintf('Un centro de color es el color promedio de un grupo en una imagen. El número de centros indica la cantidad de colores finales representados.\nSelecciona entre 2 y 10 ya que 2 es el mínimo y a partir de 5 son ejecuciones muy largas por su alta complejidad computacional.');
                app.VerimgenesButton.Tooltip = sprintf('Abrir el directorio donde se encuentran todas las imagenes guardadas en la caché.');
                app.BorrarCacheButton.Tooltip = sprintf('Borrar todas las imagenes guardadas en la caché.');
            else
                app.CargarimagendepiezametlicaButton.Tooltip = sprintf('Select an image to apply the invariant transformation algorithms. This can be either from the system or your own.');
                app.CargarimagengroundtruthopcionalButton.Tooltip = sprintf('Select a ground truth image to compare with the selected image to which invariant transformation algorithms will be applied.');
                app.EjecutarButton.Tooltip = sprintf('Execute the selected invariant transformation and clustering algorithms on the selected image.');
                app.GuardarresultadosButton.Tooltip = sprintf('Save the four solution images in the selected directory. These will be named according to the results they have obtained.');
                app.NmerodecentrosEditField.Tooltip = sprintf('A color center is the average color of a group in an image. The number of centers indicates the number of final colors represented.\nSelect between 2 and 10 as 2 is the minimum and beyond 5 are very long executions due to their high computational complexity.');
                app.VerimgenesButton.Tooltip = sprintf('Open the directory where all the images saved in the cache are located.');
                app.BorrarCacheButton.Tooltip = sprintf('Delete all cached images.');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Obtener la resolución de la pantalla
            screenSize = get(0, 'ScreenSize'); % Devuelve [left, bottom, width, height]
            
            % Obtener el tamaño de la ventana de la aplicación
            figSize = app.UIFigure.Position; % Devuelve [left, bottom, width, height]
            
            % Calcular la nueva posición para centrar la ventana
            newX = (screenSize(3) - figSize(3)) / 2; % Centrado horizontal
            newY = (screenSize(4) - figSize(4)) / 2; % Centrado vertical
            
            % Ajustar la posición de la ventana de la aplicación
            app.UIFigure.Position = [newX, newY, figSize(3), figSize(4)];
            
            % Intentar cargar las funciones y configurar la caché
            try
                % Obtener la ruta raíz
                raiz = fileparts(fileparts(app.UIFigure.FileName));
                
                % Agregar la ruta de funciones al path
                functionsPath = fullfile(raiz, 'src', 'functions');
                addpath(functionsPath);
            
                % Definir la ruta de la caché
                app.cachePath = fullfile(raiz, 'cache');
            
                % Verificar si el directorio 'cache' existe
                if ~exist(app.cachePath, 'dir')
                    mkdir(app.cachePath);
                end
            
                % Definir la ruta de los live scripts
                app.livescriptsPath = fullfile(raiz, 'src', 'livescripts');
            
                % Inicializar app.UITable.Data como una tabla vacía con las columnas correctas
                app.UITable.Data = table('Size', [0 8], ...
                         'VariableTypes', {'string', 'datetime', 'string', 'string', 'string', 'string', 'string', 'logical'}, ...
                         'VariableNames', {'NombreFichero', 'Fecha', 'AlgoritmoInvariante', 'AlgoritmoAgrupamiento', 'NumeroCentros', 'ImagenGroundTruth', 'MedidaDeCalidad', 'AbrirImagen'});

                % Cargar la tabla de caché con imágenes anteriores
                loadCacheTable(app);
            
                % Asignar el idioma inicial
                app.idioma = 'ES';
                cargarTitulo(app);     % Cargar el título
                cargarInterfaz(app);   % Cargar la interfaz
                cargarStrings(app);    % Cargar las cadenas de la interfaz
                cargarAlerts(app);     % Cargar los mensajes de error
                cargarHTML(app);       % Cargar HTML
                cargarTooltips(app);   % Cargar tooltips
            
                % Ocultar las gráficas donde se mostrarán las imágenes solución
                app.UIAxes_original.Visible = "off";
                app.UIAxes_ground_truth.Visible = "off";
                app.UIAxes_original_resultado.Visible = "off";
                app.UIAxes_original_segmentada.Visible = "off";
                app.UIAxes_invariante_resultado.Visible = "off";
                app.UIAxes_invariante_segmentada.Visible = "off";
            
                % Inicialmente no se puede cargar una imagen ground truth, ni ejecutar ni guardar
                app.CargarimagengroundtruthopcionalButton.Enable = 'off';
                app.EjecutarButton.Enable = 'off';
                app.GuardarresultadosButton.Enable = 'off';
            
            catch ME
                % Manejar cualquier error durante la configuración inicial
                uialert(app.UIFigure, ['Error during initialization: ' ME.message], 'Initialization Error', 'Icon', 'error');
            end
        end

        % Button pushed function: CargarimagendepiezametlicaButton
        function CargarimagendepiezametlicaButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);
            
            app.CargarimagendepiezametlicaButton.Enable = "off";
            
            try
                % Abro un cuadro de diálogo para seleccionar una imagen 
                % desde el directorio de imágenes predeterminado.
                raiz = fileparts(fileparts(app.UIFigure.FileName));
                directorio_origen = fullfile(raiz, 'data', 'Imagenes');
                directorio_ground_truth = fullfile(raiz, 'data', 'Imagenes_ground_truth');
                [imagen_seleccionada, ruta] = uigetfile(fullfile(directorio_origen, '*.*'), app.sel_img);
                
                % Vuelvo a la ventana de la aplicación
                figure(app.UIFigure);
            
                % Si se cancela el cuadro de diálogo
                if isequal(imagen_seleccionada, 0)
                    % Desbloqueo opciones
                    bloqueoDesbloqueoOpciones(app, 2);
            
                    % Cambiar el texto del botón de carga de imagen
                    app.CargarimagendepiezametlicaButton.Text = app.cargar_otra_img;
                    app.CargarimagendepiezametlicaButton.Enable = "on";
                    uialert(app.UIFigure, [app.seleccion_cancelada newline app.cargar_img_para_ejec], app.sel_img_cancelada);
                    return;
                end
            
                % Indica si la imagen seleccionada tiene imagen ground truth asociada
                app.tiene_img_ground_truth = false;
                
                % Limpia las visualizaciones anteriores
                cla(app.UIAxes_original);
                cla(app.UIAxes_ground_truth);
                app.UIAxes_original.Visible = 'off';
                app.UIAxes_ground_truth.Visible = 'off';
            
                cla(app.UIAxes_original_resultado);
                cla(app.UIAxes_original_segmentada);
                cla(app.UIAxes_invariante_resultado);
                cla(app.UIAxes_invariante_segmentada);
                app.UIAxes_original_resultado.Visible = "off";
                app.UIAxes_original_segmentada.Visible = "off";
                app.UIAxes_invariante_resultado.Visible = "off";
                app.UIAxes_invariante_segmentada.Visible = "off";
                app.UIAxes_original_resultado.Title.String = '';
                app.UIAxes_original_segmentada.Title.String = '';
                app.UIAxes_invariante_resultado.Title.String = '';
                app.UIAxes_invariante_segmentada.Title.String = '';
            
                app.Image.Visible = 'on';
                app.Image_2.Visible = 'on';
                app.UIAxes_original.Title.String = '';
                app.UIAxes_ground_truth.Title.String = '';
                app.imagen = [];
                app.imagen_ground_truth= [];
            
                % Inicializar la waitbar
                h = waitbar(0, app.cargando_img, 'Name', app.espere);
            
                % Extraigo el nombre y la extensión de la imagen seleccionada.
                [~, app.nombre_imagen, app.extension] = fileparts(imagen_seleccionada);
                
                % Cambio en el texto del grafico de la imagen original
                texto_imagen_original = app.img_orig;
                titleString = [texto_imagen_original ': ' app.nombre_imagen];
            
                % Evito que lo interprete como LateX
                title(app.UIAxes_original, titleString, 'Interpreter', 'none');
            
                % Actualizar waitbar
                waitbar(0.3, h, app.leyendo_img);
                
                % Leo la imagen desde su ruta completa.
                app.imagen = imread(fullfile(ruta, imagen_seleccionada));
                app.imagen_original = app.imagen;
                
                % Actualizar waitbar
                waitbar(0.6, h, app.buscando_gt);
                
                % Obtengo la ruta de la imagen ground truth en base a la seleccionada por el usuario
                posicion_inicio = strfind(ruta, directorio_origen);
                posicion_inicio = posicion_inicio + length(directorio_origen);
                ruta_intermedia = ruta(posicion_inicio:end);
                archivo_ground_truth = fullfile(directorio_ground_truth, ruta_intermedia, [app.nombre_imagen, app.extension]);
                
                % Modifico visibilidad de los objetos de la imagen
                app.Image.Visible = 'off';
                app.UIAxes_original.Visible = 'on';
            
                % Comprobar si existe la imagen ground truth
                if isfile(archivo_ground_truth)
                    % Modifico visibilidad de los objetos de la imagen ground truth
                    app.Image_2.Visible = 'off';
                    app.UIAxes_ground_truth.Visible = 'on';
                                
                    % Leo la imagen ground truth
                    app.imagen_ground_truth = imread(archivo_ground_truth);
                    app.tiene_img_ground_truth = true;
                    app.CargarimagengroundtruthopcionalButton.Enable = "off";
            
                    % Cambio en el texto del grafico de la imagen ground truth
                    texto_imagen_ground_truth = app.img_gt;
                    titleString = [texto_imagen_ground_truth ': ' app.nombre_imagen];
            
                    % Evito que lo interprete como LateX
                    title(app.UIAxes_ground_truth, titleString, 'Interpreter', 'none');
                
                    % Mostrar las imágenes donde corresponde
                    imshow(app.imagen, 'Parent', app.UIAxes_original);
                    imshow(app.imagen_ground_truth, 'Parent', app.UIAxes_ground_truth);
                else
                    % No se encontró imagen ground truth
                    imshow(app.imagen, 'Parent', app.UIAxes_original);
                    app.UIAxes_ground_truth.Visible = 'off';
                    app.CargarimagengroundtruthopcionalButton.Enable = "on";
                    uialert(app.UIFigure, [app.img_gt_no_encontrada newline app.cargar_img_gt_para_comp], app.img_gt_no_encontrada);
                end
                
                % Cambiar el texto del botón de carga de imagen
                app.CargarimagendepiezametlicaButton.Text = app.cargar_otra_img;
                
                % Al ya haber una imagen, habilito el botón de ejecutar
                app.EjecutarButton.Enable = 'on';
                
                % Cerrar waitbar
                close(h);
                
                app.CargarimagendepiezametlicaButton.Enable = "on";
            catch ME
                % Cerrar la waitbar en caso de error
                if exist('h', 'var')
                    close(h);
                end
                % Mostrar mensaje de error
                errordlg([app.error_carga_img ': ' ME.message], app.error_titulo);
                % Restaurar estado de la interfaz
                app.CargarimagendepiezametlicaButton.Enable = 'on';
                app.CargarimagengroundtruthopcionalButton.Enable = 'on';
                app.EjecutarButton.Enable = 'off';
                bloqueoDesbloqueoOpciones(app, 2);
            end
            
            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);
            
            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);
            
            app.EjecutarButton.Enable = "off";
            
            % Oculto la ejecución anterior
            cla(app.UIAxes_original_resultado);
            cla(app.UIAxes_original_segmentada);
            cla(app.UIAxes_invariante_resultado);
            cla(app.UIAxes_invariante_segmentada);
            app.UIAxes_original_resultado.Title.String = '';
            app.UIAxes_invariante_resultado.Title.String = '';
            app.UIAxes_original_segmentada.Title.String = '';
            app.UIAxes_invariante_segmentada.Title.String = '';
            % Impido que se puedan pulsar botones mientras la ejecución
            app.CargarimagendepiezametlicaButton.Enable = 'off';
            app.CargarimagengroundtruthopcionalButton.Enable = 'off';
            app.GuardarresultadosButton.Enable = 'off';
            
            try
                app.Canceling = false;
                % Inicializar la waitbar
                h = waitbar(0, app.prep_ejec, 'Name', app.ejec_alg, 'CreateCancelBtn', @(src,event)setappdata(gcbf,'canceling',1));
                setappdata(h, 'canceling', 0);
            
                % Obtengo la imagen de tres canales
                app.imagen_en_tres_canales = imagen_tres_canales(app.imagen);
            
                % Selector de invariantes
                if app.AlvarezButton.Value == 1
                    app.invariante = 'Alvarez';
                    opcion1 = 1;
                elseif app.MaddernButton.Value == 1
                    app.invariante = 'Maddern';
                    opcion1 = 2;
                elseif app.KrajnkButton.Value == 1
                    app.invariante = 'Krajnık';
                    opcion1 = 3;
                elseif app.UpcroftButton.Value == 1
                    app.invariante = 'Upcrof';
                    opcion1 = 4;
                elseif app.PCAButton.Value == 1
                    app.invariante = 'PCA';
                    opcion1 = 5;
                end
            
                % Selector de agrupamientos
                if app.KMeansButton.Value == 1
                    app.agrupamiento = 'K-Means';
                    opcion2 = 1;
                elseif app.FuzzyCMeansButton.Value == 1
                    app.agrupamiento = 'C-Means';
                    opcion2 = 2;
                elseif app.GaussianMixturesButton.Value == 1
                    app.agrupamiento = 'GMM';
                    opcion2 = 3;
                elseif app.InformacinespacialButton.Value == 1
                    app.agrupamiento = 'Spatial';
                    opcion2 = 4;
                end
            
                % Selector de clusters
                app.numClusters = app.NmerodecentrosEditField.Value;
                
                waitbar(0.15, h, app.comp_cache);
                [existe_invariante, app.imagen_invariante, ~] = check_cache(1, app.nombre_imagen, app.invariante, app.agrupamiento, app.numClusters, app.extension, app.cachePath);
                [existe_original_agrupada, app.imagen_original_agrup, app.porcentaje_original] = check_cache(2, app.nombre_imagen, app.invariante, app.agrupamiento, app.numClusters, app.extension, app.cachePath);
                [existe_invariante_agrupada, app.imagen_invariante_agrup, app.porcentaje_invariante] = check_cache(3, app.nombre_imagen, app.invariante, app.agrupamiento, app.numClusters, app.extension, app.cachePath);
            
                % Verificar si se ha presionado el botón de cancelar
                if getappdata(h, 'canceling')
                    app.Canceling = true;
                end
                
                if ~app.Canceling && ~existe_invariante
                    % Actualizar la waitbar
                    waitbar(0.25, h, [app.aplicando app.invariante app.sobre_img]);
                    % Ejecutar algoritmo de transformación invariante
                    app.imagen_invariante = metodos_invariantes(opcion1, app.imagen_en_tres_canales);
                end

                if getappdata(h, 'canceling')
                    app.Canceling = true;
                end
                
                if ~app.Canceling && ~existe_original_agrupada
                    % Actualizar la waitbar
                    waitbar(0.5, h, [app.aplicando app.agrupamiento app.sobre_img_orig]);
                    set(0, 'DefaultFigureVisible', 'off');
                    [app.imagen_original_agrup, app.porcentaje_original] = metodos_agrupamiento(opcion2, app.imagen_en_tres_canales, app.imagen_ground_truth, app.numClusters);
                    set(0, 'DefaultFigureVisible', 'on');
                end
            
                if getappdata(h, 'canceling')
                    app.Canceling = true;
                end

                if ~app.Canceling && ~existe_invariante_agrupada
                    % Actualizar la waitbar
                    waitbar(0.7, h, [app.aplicando app.agrupamiento app.sobre_img_inv]);
                    % Preparacion de imagen invariante de tres canales
                    imagen_invariante_tres = cat(3, app.imagen_invariante, app.imagen_invariante, app.imagen_invariante);
                    set(0, 'DefaultFigureVisible', 'off');
                    [app.imagen_invariante_agrup, app.porcentaje_invariante] = metodos_agrupamiento(opcion2, imagen_invariante_tres, app.imagen_ground_truth, app.numClusters);
                    set(0, 'DefaultFigureVisible', 'on');
                end

                if getappdata(h, 'canceling')
                    app.Canceling = true;
                end

                if ~app.Canceling
                    % Guardado en cache
                    waitbar(0.9, h, app.guard_cache);
                
                    currentDateTime = datetime('now', 'Format', 'yyyyMMddHHmmss');
                    fecha = char(currentDateTime);
                
                    suffix_original = ['_' num2str(app.numClusters) '_c'];
                    suffix_invariante = ['_' num2str(app.numClusters) '_c'];
                    if app.tiene_img_ground_truth
                        suffix_original = [suffix_original '_' num2str(app.porcentaje_original) '%'];
                        suffix_invariante = [suffix_invariante '_' num2str(app.porcentaje_invariante) '%'];
                    end
                
                    imwrite(app.imagen_invariante, fullfile(app.cachePath, [fecha '_1__' app.nombre_imagen '__' app.invariante app.extension]));
                    imwrite(app.imagen_original_agrup, fullfile(app.cachePath, [fecha '_2__' app.nombre_imagen '__Original_' app.agrupamiento suffix_original app.extension]));
                    imwrite(app.imagen_invariante_agrup, fullfile(app.cachePath, [fecha '_3__' app.nombre_imagen '__' app.invariante '_' app.agrupamiento suffix_invariante app.extension]));
                
                    addImageToUITable(app, app.nombre_imagen, fecha, app.invariante, '', '', '');
                    if app.tiene_img_ground_truth
                        addImageToUITable(app, app.nombre_imagen, fecha, '', app.agrupamiento, app.numClusters, app.porcentaje_original);
                        addImageToUITable(app, app.nombre_imagen, fecha, app.invariante, app.agrupamiento, app.numClusters, app.porcentaje_invariante);
                    else
                        addImageToUITable(app, app.nombre_imagen, fecha, '', app.agrupamiento, app.numClusters, '');
                        addImageToUITable(app, app.nombre_imagen, fecha, app.invariante, app.agrupamiento, app.numClusters, '');
                    end
                
                    % Muestro las imágenes
                    imshow(app.imagen_original, 'Parent', app.UIAxes_original_resultado);
                    imshow(app.imagen_original_agrup, 'Parent', app.UIAxes_original_segmentada);
                    imshow(app.imagen_invariante, 'Parent', app.UIAxes_invariante_resultado);
                    imshow(app.imagen_invariante_agrup, 'Parent', app.UIAxes_invariante_segmentada);
                    app.UIAxes_original_resultado.Title.String = app.img_orig;
                    app.UIAxes_invariante_resultado.Title.String = 'Imagen invariante';
                    if app.tiene_img_ground_truth
                        app.UIAxes_original_segmentada.Title.String = [app.acierto num2str(app.porcentaje_original, '%.2f') '%'];
                        app.UIAxes_invariante_segmentada.Title.String = [app.acierto num2str(app.porcentaje_invariante, '%.2f') '%'];
                    else
                        app.UIAxes_original_segmentada.Title.String = app.orig_agrup;
                        app.UIAxes_invariante_segmentada.Title.String = app.inv_agrup;
                    end
                    app.GuardarresultadosButton.Text = app.guardar_res;
                
                    waitbar(1, h, app.proc_compl);
                end
                delete(h);
            catch ME
                % Cerrar la waitbar en caso de error
                if exist('h', 'var')
                    delete(h);
                end
                % Mostrar mensaje de error
                errordlg([app.error_ejec ': ' ME.message], app.error_titulo);
                waitforbuttonpress;

                % Restaurar estado de la interfaz
                app.GuardarresultadosButton.Enable = 'off';
            end

            % Al ya haber resultados, habilito el boton de guardar
            app.CargarimagendepiezametlicaButton.Enable = 'on';
            if app.tiene_img_ground_truth
                app.CargarimagengroundtruthopcionalButton.Enable = 'off';
            else
                app.CargarimagengroundtruthopcionalButton.Enable = 'on';
            end
            app.EjecutarButton.Enable = 'on';

            if ~app.Canceling
                app.GuardarresultadosButton.Enable = 'on';
            end
            
            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);

            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);
            
            app.GuardarresultadosButton.Enable = "off";
            
            % Define una ubicación por defecto
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            ubicacion_por_defecto = fullfile(raiz, 'results');
            
            % Abre un cuadro de diálogo para que el usuario elija una ubicación
            ubicacion = uigetdir(ubicacion_por_defecto, app.sel_ubicacion);
            
            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);
            
            % Si el usuario presiona cancelar, uigetdir retorna 0
            if ubicacion == 0
                uialert(app.UIFigure, app.no_ubicacion_sel, app.ubicacion_no_sel);
                ubicacion = ubicacion_por_defecto;
            end
            
            % Inicializa la waitbar
            h = waitbar(0, app.guardando_imgs, 'Name', app.guardado_imgs); 
            
            try
                currentDateTime = datetime('now', 'Format', 'yyyyMMddHHmmss');
                fecha = char(currentDateTime);
            
                switch app.agrupamiento
                    case 'K-Means'
                        agrup = 'KM';
                    case 'C-Means'
                        agrup = 'CM';
                    case 'GMM'
                        agrup = 'GM';
                    case 'Spatial'
                        agrup = 'SP';
                end
            
                % Guarda las imágenes y actualiza la waitbar
                waitbar(0.25, h, app.guard_img_orig);
                imwrite(app.imagen_original, fullfile(ubicacion, [fecha app.extension]));
                
                waitbar(0.50, h, [app.guard_img_orig_agrup app.agrupamiento newline app.con_centros app.numClusters app.centros]);
                if app.tiene_img_ground_truth
                    imwrite(app.imagen_original_agrup, fullfile(ubicacion, [fecha '_Ori_' agrup '_' num2str(app.numClusters) 'c_' num2str(round(app.porcentaje_original * 100)) app.extension]));
                else
                    imwrite(app.imagen_original_agrup, fullfile(ubicacion, [fecha '_Ori_' agrup '_' num2str(app.numClusters) 'c' app.extension]));
                end
            
                waitbar(0.75, h, [app.guard_img_inv app.invariante ')...']);
                imwrite(app.imagen_invariante, fullfile(ubicacion, [fecha '_' app.invariante(1:3) app.extension]));
                
                waitbar(1.0, h, [app.guard_img_inv app.invariante ') ' newline app.agrup_str app.agrupamiento app.con_centros app.numClusters ' centros)...']);
                if app.tiene_img_ground_truth
                    imwrite(app.imagen_invariante_agrup, fullfile(ubicacion, [fecha '_' app.invariante(1:3) '_' agrup '_' num2str(app.numClusters) 'c_' num2str(round(app.porcentaje_original * 100)) app.extension]));
                else
                    imwrite(app.imagen_invariante_agrup, fullfile(ubicacion, [fecha '_' app.invariante(1:3) '_' agrup '_' num2str(app.numClusters) 'c' app.extension]));
                end
            
                % Cerrar waitbar
                close(h);
                
                % Mensaje de confirmación
                uialert(app.UIFigure, app.imgs_guardadas_correct, app.guardado_exitoso, 'Icon', 'success');
                
                % Cambia el estado y texto del botón
                app.GuardarresultadosButton.Enable = 'off';
                app.GuardarresultadosButton.Text = app.imgs_guardadas;
            catch e
                % Cerrar waitbar si hay error
                if exist('h', 'var')
                    close(h);
                end
                
                % Mensaje de error
                uialert(app.UIFigure, [app.error_guardar_imgs ' ' e.message], app.error_guardado);
                
                % Restablecer el botón
                app.GuardarresultadosButton.Enable = 'on';
                app.GuardarresultadosButton.Text = app.int_guardar_nuev;
            end
            
            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);
            
            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);
        end

        % Button pushed function: CargarimagengroundtruthopcionalButton
        function CargarimagengroundtruthopcionalButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);
            
            app.CargarimagengroundtruthopcionalButton.Enable = "off";
            
            try
                % Abre un cuadro de diálogo para seleccionar la imagen ground truth
                [imagen_gt_seleccionada, ruta_gt] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', app.imgs_gt}, app.selecciona_img_gt);
                
                % Vuelvo a la ventana de la aplicación
                figure(app.UIFigure);
            
                % Si se cancela el cuadro de diálogo
                if isequal(imagen_gt_seleccionada, 0)
                    % Desbloqueo opciones
                    bloqueoDesbloqueoOpciones(app, 2);
                    uialert(app.UIFigure, [app.sel_img_gt_cancelada newline app.cargar_img_gt_para_comp], app.sel_img_gt_cancelada);
                    app.CargarimagengroundtruthopcionalButton.Text = app.cargar_otra_gt;
                    app.CargarimagengroundtruthopcionalButton.Enable = "on";
                    return;
                end
            
                % Intenta leer la imagen ground truth
                app.UIAxes_ground_truth.Visible = 'on';
                app.Image_2.Visible = 'off';
            
                % Asigna la imagen ground truth a la variable y la muestra
                app.imagen_ground_truth = imread(fullfile(ruta_gt, imagen_gt_seleccionada));
            
                % Extraigo el nombre y la extensión de la imagen seleccionada.
                [~, nombre_gt, ~] = fileparts(imagen_gt_seleccionada);
            
                % Cambio en el texto del grafico de la imagen ground truth
                texto_imagen_ground_truth = app.img_gt;
                titleString = [texto_imagen_ground_truth ': ' nombre_gt];
            
                % Evito que lo interprete como LateX
                title(app.UIAxes_ground_truth, titleString, 'Interpreter', 'none');
            
                imshow(app.imagen_ground_truth, 'Parent', app.UIAxes_ground_truth);
                
                % Actualizar estado para indicar que ahora hay una imagen ground truth
                app.tiene_img_ground_truth = true;
                app.CargarimagengroundtruthopcionalButton.Text = app.cargar_otra_gt;
            catch ME
                % Mensaje de error en caso de fallo al leer la imagen
                uialert(app.UIFigure, [app.error_lectura_gt ': ' ME.message], app.error_lectura);
            end
            
            app.CargarimagengroundtruthopcionalButton.Enable = "on";
            
            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);

            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try    
                % Mostrar un cuadro de confirmación antes de salir de la aplicación
                selection = uiconfirm(app.UIFigure, app.seguro_salir, ...
                                                  app.conf_cierre, ...
                                                  'Options', {app.salir, app.cancelar}, ...
                                                  'DefaultOption', 1, 'CancelOption', 2);
                                              
                % Verificar la selección del usuario
                if strcmp(selection, app.salir)
                    % Asegurar que la interfaz se actualiza antes de cerrar
                    drawnow;
                    
                    % Intentar cerrar la aplicación
                    delete(app);
                end
            catch ME
                % Manejar cualquier error que ocurra durante el cierre
                uialert(app.UIFigure, [app.error_cerrar_app ': ' ME.message], app.error_titulo);
            end
        end

        % Button pushed function: VerimgenesButton
        function VerimgenesButtonPushed(app, event)
            % Intenta abrir la carpeta de caché según el sistema operativo
            try
                if ispc
                    % Abre la carpeta de caché en Windows
                    winopen(app.cachePath);
                elseif ismac
                    % Abre la carpeta de caché en macOS
                    system(['open ', app.cachePath]);
                elseif isunix
                    % Abre la carpeta de caché en sistemas Unix/Linux
                    system(['xdg-open ', app.cachePath]);
                else
                    % Mensaje de error si el sistema operativo no es reconocido
                    error_msg = 'Unable to open cache folder on this operating system';
                    if strcmp(app.idioma, 'ES')
                        error_msg = 'No se puede abrir la carpeta de caché en este sistema operativo';
                    end
                    msgbox(error_msg, 'Error', 'error');
                end
            catch ME
                % Maneja cualquier error que ocurra al intentar abrir la carpeta
                if strcmp(app.idioma, 'ES')
                    error_msg = ['Error al intentar abrir la carpeta de caché: ' ME.message];
                else
                    error_msg = ['Error while trying to open the cache folder: ' ME.message];
                end
                msgbox(error_msg, 'Error', 'error');
            end
        end

        % Value changed function: NmerodecentrosEditField
        function NmerodecentrosEditFieldValueChanged(app, event)
            % Obtener el valor del campo de edición
            value = app.NmerodecentrosEditField.Value;
            
            % Validar el valor ingresado
            if value < 2
                % Mostrar alerta si el valor es menor que 2
                uialert(app.UIFigure, [app.centros_introducidos_menor num2str(value) app.menor_minimo_2], app.error_num_centros);
                % Establecer el valor mínimo permitido
                app.NmerodecentrosEditField.Value = 2;
            elseif value > 10
                % Mostrar alerta si el valor es mayor que 10
                uialert(app.UIFigure, [app.centros_introducidos_menor num2str(value) app.centros_introducidos_alta], app.error_num_centros);
                % Establecer un valor predeterminado en caso de valor fuera de rango superior
                app.NmerodecentrosEditField.Value = 2;
            elseif value > 5
                % Mostrar advertencia si el valor es mayor que 5 pero menor o igual a 10
                uialert(app.UIFigure, [app.centros_introducidos_menor num2str(value) app.valor_alto_recomendado], app.error_num_centros);
                % No cambiar el valor, solo advertir al usuario
            end
            
            % Asignar el valor a numClusters si está dentro del rango permitido
            if value >= 2 && value <= 10
                app.numClusters = value;
            end
        end

        % Button pushed function: BorrarCacheButton
        function BorrarCacheButtonPushed(app, event)
            % Mostrar un cuadro de confirmación antes de borrar la cache
            selection = uiconfirm(app.UIFigure, app.seguro_borrar, ...
                                              app.conf_borrado, ...
                                              'Options', {app.borrar, app.cancelar}, ...
                                              'DefaultOption', 1, 'CancelOption', 2);
                                          
            % Verificar la selección del usuario
            if strcmp(selection, app.borrar)
                try
                    % Obtener la lista de archivos en la carpeta de caché
                    cacheFiles = dir(fullfile(app.cachePath, '*.*'));
                    % Filtrar los archivos no deseados
                    cacheFiles = cacheFiles(~ismember({cacheFiles.name}, {'.', '..'}));
                    
                    % Verificar si hay archivos en la carpeta de caché
                    if ~isempty(cacheFiles)
                        % Intentar eliminar cada archivo en la carpeta de caché
                        for cacheFile = cacheFiles'
                            delete(fullfile(app.cachePath, cacheFile.name));
                        end
                    end
                    
                    % Vaciar la tabla en la interfaz de usuario
                    if strcmp(app.idioma, 'ES')
                        app.UITable.Data = table('Size', [0 8], ...
                                                 'VariableTypes', {'string', 'datetime', 'string', 'string', 'string', 'string', 'string', 'logical'}, ...
                                                 'VariableNames', {'NombreFichero', 'Fecha', 'AlgoritmoInvariante', 'AlgoritmoAgrupamiento', 'NumeroCentros', 'ImagenGroundTruth', 'MedidaDeCalidad', 'AbrirImagen'});
                    else
                        app.UITable.Data = table('Size', [0 8], ...
                                                 'VariableTypes', {'string', 'datetime', 'string', 'string', 'string', 'string', 'string', 'logical'}, ...
                                                 'VariableNames', {'File Name', 'Date', 'Invariant Algorithm', 'Clustering Algorithm', 'Number of Centers', 'Ground Truth Image', 'Quality Measure', 'File'});
                    end
                
                    % Mostrar alerta de éxito
                    uialert(app.UIFigure, app.cache_borrada, app.alerta);
                
                catch ME
                    % Manejar cualquier error durante la eliminación de archivos o actualización de la tabla
                    if strcmp(app.idioma, 'ES')
                        errorMsg = ['Error al intentar borrar la caché: ' ME.message];
                    else
                        errorMsg = ['Error while trying to clear the cache: ' ME.message];
                    end
                    uialert(app.UIFigure, errorMsg, app.error_titulo, 'Icon', 'error');
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % Actualizar el idioma dependiendo del interruptor
            app.idioma = app.Switch.Value;
            
            try
                % Cambio del idioma
                cargarTitulo(app);
                cargarInterfaz(app);
                cargarStrings(app);
                cargarAlerts(app);
                cargarHTML(app);
                cargarTooltips(app);
            catch ME
                % Manejar cualquier error durante el cambio de idioma
                if strcmp(app.idioma, 'ES')
                    errorMsg = ['Error al cambiar el idioma: ' ME.message];
                    msgbox(errorMsg, 'Error', 'error');
                else
                    errorMsg = ['Error while changing the language: ' ME.message];
                    msgbox(errorMsg, 'Error', 'error');
                end
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if isempty(indices)
                return;
            end
        
            row = indices(1);
            col = indices(2);
        
            % Verificar la columna para realizar la acción correspondiente
            if col == 8
                % Obtener el contenido de la fila seleccionada
                nombreFichero = app.UITable.Data.NombreFichero{row};
                fecha = datetime(app.UITable.Data.Fecha(row), 'Format', 'yyyyMMddHHmmss');
                algoritmoInvariante = app.UITable.Data.AlgoritmoInvariante{row};
                algoritmoAgrupamiento = app.UITable.Data.AlgoritmoAgrupamiento{row};
                numCentros = app.UITable.Data.NumeroCentros{row};
                medidaCalidad = app.UITable.Data.MedidaDeCalidad{row};
            
                % Determinar el patrón para construir el nombre del archivo
                if strcmp(algoritmoAgrupamiento, 'No aplicado')
                    baseName = sprintf('%s_1__%s__%s', fecha, nombreFichero, algoritmoInvariante);
                elseif strcmp(algoritmoInvariante, 'No aplicado')
                    if isempty(medidaCalidad)
                        baseName = sprintf('%s_2__%s__Original_%s_%s_c', fecha, nombreFichero, algoritmoAgrupamiento, numCentros);
                    else
                        baseName = sprintf('%s_2__%s__Original_%s_%s_c_%s', fecha, nombreFichero, algoritmoAgrupamiento, numCentros, medidaCalidad);
                    end
                else
                    if isempty(medidaCalidad)
                        baseName = sprintf('%s_3__%s__%s_%s_%s_c', fecha, nombreFichero, algoritmoInvariante, algoritmoAgrupamiento, numCentros);
                    else
                        baseName = sprintf('%s_3__%s__%s_%s_%s_c_%s', fecha, nombreFichero, algoritmoInvariante, algoritmoAgrupamiento, numCentros, medidaCalidad);
                    end
                end
            
                % Lista de extensiones de imagen comunes
                extensions = {'.jpg', '.jpeg', '.png', '.bmp', '.tiff', '.gif', '.JPG', '.JPEG', '.PNG', '.BMP', '.TIFF', '.GIF'};
                
                % Verificar la existencia del archivo con diferentes extensiones
                imagenPath = '';
                for i = 1:length(extensions)
                    tempPath = fullfile(app.cachePath, [baseName extensions{i}]);
                    if exist(tempPath, 'file')
                        imagenPath = tempPath;
                        ext = extensions{i};
                        break;
                    end
                end
            
                % Verificar si se encontró el archivo
                if ~isempty(imagenPath)
                    % Abrir la imagen
                    hFig = figure('Name', [app.imagen_text ': ' baseName ext], 'NumberTitle', 'off');
                    
                    % Crear ejes en la figura
                    hAxes = axes('Parent', hFig);
                    
                    % Mostrar la imagen en los ejes especificados
                    imshow(imread(imagenPath), 'Parent', hAxes);
                else
                    uialert(app.UIFigure, [app.archivo_img baseName app.no_extension], 'Error');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout10);
            app.BienvenidaTab = uitab(app.TabGroup);
            app.BienvenidaTab.Title = 'Bienvenida';
            app.GridLayout9 = uigridlayout(app.BienvenidaTab);
            app.HTML_Inicio = uihtml(app.GridLayout9);
            app.GridLayout34 = uigridlayout(app.GridLayout9);
            app.GridLayout35 = uigridlayout(app.GridLayout34);
            app.Image2 = uiimage(app.GridLayout35);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'livescripts', 'img', 'ES.png');
            app.Image3 = uiimage(app.GridLayout35);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'livescripts', 'img', 'UK.png');
            app.Switch = uiswitch(app.GridLayout35, 'slider');
            app.Switch.Items = {'ES', 'EN'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'ES';
            app.ExploraciondealgoritmosTab = uitab(app.TabGroup);
            app.ExploraciondealgoritmosTab.Title = 'Exploración de algoritmos';
            app.GridLayout27 = uigridlayout(app.ExploraciondealgoritmosTab);
            app.ResultadosPanel = uipanel(app.GridLayout27);
            app.ResultadosPanel.Title = 'Resultados';
            app.GridLayout3 = uigridlayout(app.ResultadosPanel);
            app.UIAxes_original_resultado = uiaxes(app.GridLayout3);
            title(app.UIAxes_original_resultado, 'Imagen original')
            app.UIAxes_original_resultado.XTick = [];
            app.UIAxes_original_resultado.YTick = [];
            app.UIAxes_original_segmentada = uiaxes(app.GridLayout3);
            title(app.UIAxes_original_segmentada, 'Original segmentada')
            zlabel(app.UIAxes_original_segmentada, 'Z')
            app.UIAxes_original_segmentada.XTick = [];
            app.UIAxes_original_segmentada.YTick = [];
            app.UIAxes_invariante_resultado = uiaxes(app.GridLayout3);
            title(app.UIAxes_invariante_resultado, 'Imagen invariante')
            zlabel(app.UIAxes_invariante_resultado, 'Z')
            app.UIAxes_invariante_resultado.XTick = [];
            app.UIAxes_invariante_resultado.YTick = [];
            app.UIAxes_invariante_segmentada = uiaxes(app.GridLayout3);
            title(app.UIAxes_invariante_segmentada, 'Invariante segmentada')
            zlabel(app.UIAxes_invariante_segmentada, 'Z')
            app.UIAxes_invariante_segmentada.XTick = [];
            app.UIAxes_invariante_segmentada.YTick = [];
            app.GuardarresultadosButton = uibutton(app.GridLayout27, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.EjecutarButton = uibutton(app.GridLayout27, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.MtodoinvarianteButtonGroup = uibuttongroup(app.GridLayout29);
            app.MtodoinvarianteButtonGroup.Title = 'Método invariante';
            app.AlvarezButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.AlvarezButton.Text = 'Alvarez';
            app.AlvarezButton.Value = true;
            app.MaddernButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddernButton.Text = 'Maddern';
            app.KrajnkButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.KrajnkButton.Text = 'Krajnık';
            app.UpcroftButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.UpcroftButton.Text = 'Upcroft';
            app.PCAButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.GridLayout30 = uigridlayout(app.GridLayout29);
            app.MtododeagrupamientoButtonGroup = uibuttongroup(app.GridLayout30);
            app.MtododeagrupamientoButtonGroup.Title = 'Método de agrupamiento';
            app.KMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.KMeansButton.Text = 'K-Means';
            app.KMeansButton.Value = true;
            app.FuzzyCMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.FuzzyCMeansButton.Text = 'Fuzzy C-Means';
            app.GaussianMixturesButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.GaussianMixturesButton.Text = 'Gaussian Mixtures';
            app.InformacinespacialButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.InformacinespacialButton.Text = 'Información espacial';
            app.Panel_2 = uipanel(app.GridLayout30);
            app.GridLayout31 = uigridlayout(app.Panel_2);
            app.NmerodecentrosEditField = uieditfield(app.GridLayout31, 'numeric');
            app.NmerodecentrosEditField.Limits = [2 Inf];
            app.NmerodecentrosEditField.RoundFractionalValues = 'on';
            app.NmerodecentrosEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodecentrosEditFieldValueChanged, true);
            app.NmerodecentrosEditField.Value = 2;
            app.NmerodecentrosLabel = uilabel(app.GridLayout31);
            app.NmerodecentrosLabel.Text = 'Número de centros';
            app.Panel = uipanel(app.GridLayout28);
            app.GridLayout32 = uigridlayout(app.Panel);
            app.UIAxes_ground_truth = uiaxes(app.GridLayout32);
            title(app.UIAxes_ground_truth, 'Imagen ground truth')
            app.UIAxes_ground_truth.XTick = [];
            app.UIAxes_ground_truth.XTickLabel = '';
            app.UIAxes_ground_truth.YTick = [];
            app.UIAxes_ground_truth.YTickLabel = '';
            app.UIAxes_original = uiaxes(app.GridLayout32);
            title(app.UIAxes_original, 'Imagen original')
            app.UIAxes_original.XTick = [];
            app.UIAxes_original.XTickLabel = '';
            app.UIAxes_original.YTick = [];
            app.UIAxes_original.YTickLabel = '';
            app.Image = uiimage(app.GridLayout32);
            app.Image.Enable = 'off';
            app.CargarimagendepiezametlicaButton = uibutton(app.GridLayout32, 'push');
            app.CargarimagendepiezametlicaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagendepiezametlicaButtonPushed, true);
            app.CargarimagendepiezametlicaButton.Text = 'Cargar imagen de pieza metálica';
            app.Image_2 = uiimage(app.GridLayout32);
            app.Image_2.Enable = 'off';
            app.CargarimagengroundtruthopcionalButton = uibutton(app.GridLayout32, 'push');
            app.CargarimagengroundtruthopcionalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagengroundtruthopcionalButtonPushed, true);
            app.CargarimagengroundtruthopcionalButton.Text = 'Cargar imagen ground truth (opcional)';
            app.CacheTab = uitab(app.TabGroup);
            app.CacheTab.Title = 'Histórico de exploraciones';
            app.GridLayout11 = uigridlayout(app.CacheTab);
            app.UITable = uitable(app.GridLayout11);
            app.UITable.ColumnName = {'Nombre del fichero'; 'Fecha'; 'Algoritmo invariante'; 'Algoritmo de agrupamiento'; 'Número de centros'; 'Imagen ground truth'; 'Medida de calidad'; 'Archivo'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true true false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayout33 = uigridlayout(app.GridLayout11);
            app.VerimgenesButton = uibutton(app.GridLayout33, 'push');
            app.VerimgenesButton.ButtonPushedFcn = createCallbackFcn(app, @VerimgenesButtonPushed, true);
            app.VerimgenesButton.Text = 'Ver imágenes';
            app.BorrarCacheButton = uibutton(app.GridLayout33, 'push');
            app.BorrarCacheButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarCacheButtonPushed, true);
            app.BorrarCacheButton.Text = 'Borrar Datos';
            app.AyudaTab = uitab(app.TabGroup);
            app.AyudaTab.Title = 'Ayuda';
            app.GridLayout6 = uigridlayout(app.AyudaTab);
            app.Menu_Ayuda = uitabgroup(app.GridLayout6);
            app.FuncionamientoTab = uitab(app.Menu_Ayuda);
            app.FuncionamientoTab.Title = 'Funcionamiento';
            app.GridLayout26 = uigridlayout(app.FuncionamientoTab);
            app.HTML_funcionamiento = uihtml(app.GridLayout26);
            app.MetodosInvariantesTab = uitab(app.Menu_Ayuda);
            app.MetodosInvariantesTab.Title = 'Métodos invariantes';
            app.GridLayout19 = uigridlayout(app.MetodosInvariantesTab);
            app.TabInvariantesTipos = uitabgroup(app.GridLayout19);
            app.AlvarezTab = uitab(app.TabInvariantesTipos);
            app.AlvarezTab.Title = 'Alvarez';
            app.GridLayout20 = uigridlayout(app.AlvarezTab);
            app.HTML_Alvarez = uihtml(app.GridLayout20);
            app.MaddernTab = uitab(app.TabInvariantesTipos);
            app.MaddernTab.Title = 'Maddern';
            app.GridLayout21 = uigridlayout(app.MaddernTab);
            app.HTML_Maddern = uihtml(app.GridLayout21);
            app.KrajnkTab = uitab(app.TabInvariantesTipos);
            app.KrajnkTab.Title = 'Krajnık';
            app.GridLayout22 = uigridlayout(app.KrajnkTab);
            app.HTML_Krajnik = uihtml(app.GridLayout22);
            app.UpcrofTab = uitab(app.TabInvariantesTipos);
            app.UpcrofTab.Title = 'Upcrof';
            app.GridLayout23 = uigridlayout(app.UpcrofTab);
            app.HTML_Upcrof = uihtml(app.GridLayout23);
            app.PCATab = uitab(app.TabInvariantesTipos);
            app.PCATab.Title = 'PCA';
            app.GridLayout25 = uigridlayout(app.PCATab);
            app.HTML_PCA = uihtml(app.GridLayout25);
            app.MetodosAgrupamientoTab = uitab(app.Menu_Ayuda);
            app.MetodosAgrupamientoTab.Title = 'Métodos de agrupamiento';
            app.GridLayout12 = uigridlayout(app.MetodosAgrupamientoTab);
            app.TabAgrupamientoTipos = uitabgroup(app.GridLayout12);
            app.KMeansTab = uitab(app.TabAgrupamientoTipos);
            app.KMeansTab.Title = 'K-Means';
            app.GridLayout13 = uigridlayout(app.KMeansTab);
            app.HTML_K_Means = uihtml(app.GridLayout13);
            app.CMeansTab = uitab(app.TabAgrupamientoTipos);
            app.CMeansTab.Title = 'C-Means';
            app.GridLayout14 = uigridlayout(app.CMeansTab);
            app.HTML_Fuzzy_C_Means = uihtml(app.GridLayout14);
            app.GMMTab = uitab(app.TabAgrupamientoTipos);
            app.GMMTab.Title = 'GMM';
            app.GridLayout15 = uigridlayout(app.GMMTab);
            app.HTML_GMM = uihtml(app.GridLayout15);
            app.SpatialTab = uitab(app.TabAgrupamientoTipos);
            app.SpatialTab.Title = 'Spatial';
            app.GridLayout16 = uigridlayout(app.SpatialTab);
            app.HTML_Spatial = uihtml(app.GridLayout16);
            app.PorcentajeAciertoTab = uitab(app.Menu_Ayuda);
            app.PorcentajeAciertoTab.Title = 'Porcentaje de acierto';
            app.GridLayout17 = uigridlayout(app.PorcentajeAciertoTab);
            app.HTML_Acierto = uihtml(app.GridLayout17);
            app.MemoriaCacheTab = uitab(app.Menu_Ayuda);
            app.MemoriaCacheTab.Title = 'Memoria caché';
            app.GridLayout18 = uigridlayout(app.MemoriaCacheTab);
            app.HTML_Cache = uihtml(app.GridLayout18);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.CargarimagendepiezametlicaButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InvIPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end